classdef apptrial1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        loadmassEditFieldLabel  matlab.ui.control.Label
        load_mass               matlab.ui.control.NumericEditField
        go                      matlab.ui.control.Button
        springDropDownLabel     matlab.ui.control.Label
        springDropDown          matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: go
        function goButtonPushed(app, event)
            addpath(genpath(pwd));
            load = load_mass(app.load_mass.Value);
            motor = linear_motor(100,100,10);
            spring = linear_spring(5,10);
            latch = rounded_latch(5,50);
            dateString = string(datetime);
            cleanDateString = regexprep(dateString, " ", "_");
            cleanDateString = regexprep(cleanDateString, ":", "_");
            sol = solve_model(motor,motor,load,latch,spring,cleanDateString)
            figure()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.loadmassEditFieldLabel = uilabel(app.UIFigure);
            app.loadmassEditFieldLabel.Text = 'load mass =';
            app.load_mass = uieditfield(app.UIFigure, 'numeric');
            app.go = uibutton(app.UIFigure, 'push');
            app.go.ButtonPushedFcn = createCallbackFcn(app, @goButtonPushed, true);
            app.springDropDownLabel = uilabel(app.UIFigure);
            app.springDropDownLabel.Text = 'spring';
            app.springDropDown = uidropdown(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = apptrial1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end